classdef stc_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        channelListBoxLabel  matlab.ui.control.Label
        channelListBox       matlab.ui.control.ListBox
        TabGroup             matlab.ui.container.TabGroup
        stimTab              matlab.ui.container.Tab
        infoTextAreaLabel    matlab.ui.control.Label
        infoTextArea         matlab.ui.control.TextArea
        STATab               matlab.ui.container.Tab
        STA_Axes             matlab.ui.control.UIAxes
        STA_slice_OFF_Axes   matlab.ui.control.UIAxes
        STA_slice_max_Axes   matlab.ui.control.UIAxes
        STCTab               matlab.ui.container.Tab
        STC_Axes_ev          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        channel_names
        selected_channel_index
        
%         stim             % input stim
%         spike_train      % measured spike response
        
        sta_all_channels
        sta_num_samples
        
        height
        width
        
        sampling_rate
        gridT
        
        %sta_num_samples
        %sta_result

        stc_u                       %                   1x156              8569872  cell         
        stc_ev                      %                   1x156               116776  cell                                                 
        stc_score                   %                   1x156            162471552  cell                                                 
        stc_significant_ev_idx      %                   1x156                18104  cell                                                 
  
        
    end
    
    methods (Access = private)

%         function plot_spike_train(app)
%             bar(app.spike_Axes, app.spike_train(:,app.selected_channel_index))
%         end
    
    
%         function run_sta(app) %, stim, spike_train, sta_num_samples)
%             % run STA
%             app.sta_result = calc_STA_and_STC(app.stim, app.spike_train(:,app.selected_channel_index), app.sta_num_samples);
%             
%             % plot
%             plot(app.STA_Axes, app.sta_result)
%             
%         end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% PLOTTING FUNCTIONS ARE HERE
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function plot_sta(app)

            sta = app.sta_all_channels{app.selected_channel_index};
            sta = reshape(sta, app.sta_num_samples, []);        
            axes = app.STA_Axes
            grid = app.gridT*1000
            
        
            % plot
            plot(axes, grid, sta)
            
%             app.STA_Axes.XLabel = 'ms'
%             app.STA_Axes.YLabel = 'stim intensity'
            axis.YLim = [0 1];
            
        end

        function plot_sta_slice(app)
            
            FLIP_XY = true;
            
%             r=floor(sqrt(app.sta_num_samples));
%             c=ceil(app.sta_num_samples/r);
%             cnt=1;
%             
            sta = app.sta_all_channels{app.selected_channel_index};
            
            sta = reshape(sta, app.sta_num_samples, []);    
            
            [max_val, max_slice_idx] =  max(max(sta, [], 2));
            [min_val, min_slice_idx] =  min(min(sta, [], 2));
            
            if FLIP_XY
                imagesc(reshape(sta(max_slice_idx,:), app.height, app.width)', 'Parent', app.STA_slice_max_Axes)
            else
                imagesc(reshape(sta(max_slice_idx,:), app.height, app.width), 'Parent', app.STA_slice_max_Axes)
            end
            app.STA_slice_max_Axes.XLim = [1 app.width];
            app.STA_slice_max_Axes.YLim = [1 app.height];
            app.STA_slice_max_Axes.CLim = [0.2 0.8];            
            app.STA_slice_max_Axes.Colormap = gray;
            axis(app.STA_slice_max_Axes,'off')
            axis(app.STA_slice_max_Axes,'xy')
            
            if FLIP_XY
                imagesc(reshape(sta(min_slice_idx,:), app.height, app.width)', 'Parent', app.STA_slice_OFF_Axes)
            else 
                imagesc(reshape(sta(min_slice_idx,:), app.height, app.width), 'Parent', app.STA_slice_OFF_Axes)
            end
            app.STA_slice_OFF_Axes.XLim = [1 app.width];
            app.STA_slice_OFF_Axes.YLim = [1 app.height];
            app.STA_slice_OFF_Axes.CLim = [0.2 0.8];            
            app.STA_slice_OFF_Axes.Colormap = gray;
            axis(app.STA_slice_OFF_Axes,'off')
            axis(app.STA_slice_OFF_Axes,'xy')
            

        end
    
        function results = plot_stc(app)
            plot(app.STC_Axes_ev, app.stc_ev{app.selected_channel_index}, 'o:', 'MarkerSize', 5)
            
        end
        
%         function results = plot_STA(app, channel_index)
%             %app.infoTextArea.Value = ['function called with '  channel_name]
%             plot(app.STA_Axes, [1 2 3 4], [1 2 3 4], '-or')
%             
%         end
    
        function str = print_results(app)
            str = sprintf('ch name=%s',  app.channel_names{app.selected_channel_index});
            str = [str ', ' sprintf('ch index=%d', app.selected_channel_index)];
            
            app.infoTextArea.Value = str;
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            % load channel names from the workspace
            app.channel_names = evalin('base','channel_names');
            app.channelListBox.Items = app.channel_names;
            app.channelListBox.ItemsData = 1:length(app.channel_names);


%             % load spike train data from the workspace
%             app.stim = evalin('base','stim');
%             
%             % load spike train data from the workspace
%             app.spike_train = evalin('base','spike_train');
           
            % load sta results for all channels
            app.sta_all_channels = evalin('base','sta_all_channels');
            app.sta_num_samples = evalin('base','sta_num_samples');
            app.height = sqrt(size(app.sta_all_channels{1},2)/app.sta_num_samples);
            app.width = app.height
            
            % sta param
            %app.sta_num_samples = 20;
            
            app.sampling_rate = evalin('base', 'sampling_rate');
            app.gridT = (-app.sta_num_samples+1:0)/app.sampling_rate;
            
            
            % load stc results
            app.stc_u = evalin('base', 'stc_u');
            app.stc_ev = evalin('base', 'stc_ev');
            
            app.infoTextArea.Value = 'Ready to run!';
            
            
            % run for the first imte
            app.selected_channel_index = 1;
            
            %plot_spike_train(app)
            
            % plot STA
            plot_sta(app)
            
            % plot sta slice
            plot_sta_slice(app)
            
            % plot STC
            plot_stc(app)
            
        end

        % Value changed function: channelListBox
        function channelListBoxValueChanged(app, event)
            %% get selected channel index
            app.selected_channel_index = app.channelListBox.Value
            
            % plot spike train
            %plot_spike_train(app)
            
            % plot sta
            plot_sta(app)
            
            % plot sta slice
            plot_sta_slice(app)
                        
            % plot stc
            plot_stc(app)
            
            % print results
            print_results(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.channelListBoxLabel = uilabel(app.UIFigure);
            app.channelListBoxLabel.Text = 'channel';
            app.channelListBox = uilistbox(app.UIFigure);
            app.channelListBox.ValueChangedFcn = createCallbackFcn(app, @channelListBoxValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.stimTab = uitab(app.TabGroup);
            app.stimTab.Title = 'stim';
            app.infoTextAreaLabel = uilabel(app.stimTab);
            app.infoTextAreaLabel.Text = 'info';
            app.infoTextArea = uitextarea(app.stimTab);
            app.STATab = uitab(app.TabGroup);
            app.STATab.Title = 'STA';
            app.STA_Axes = uiaxes(app.STATab);
            title(app.STA_Axes, 'STA')
            xlabel(app.STA_Axes, 'time prior to spike onse (ms)')
            ylabel(app.STA_Axes, 'stim intensity')
            app.STA_slice_OFF_Axes = uiaxes(app.STATab);
            title(app.STA_slice_OFF_Axes, 'STA slice with min value (OFF)')
            xlabel(app.STA_slice_OFF_Axes, 'x')
            ylabel(app.STA_slice_OFF_Axes, 'y')
            app.STA_slice_OFF_Axes.CLim = [0 1];
            app.STA_slice_max_Axes = uiaxes(app.STATab);
            title(app.STA_slice_max_Axes, 'STA slice with max value (ON)')
            xlabel(app.STA_slice_max_Axes, 'X')
            ylabel(app.STA_slice_max_Axes, 'Y')
            app.STCTab = uitab(app.TabGroup);
            app.STCTab.Title = 'STC';
            app.STC_Axes_ev = uiaxes(app.STCTab);
            title(app.STC_Axes_ev, '')
            xlabel(app.STC_Axes_ev, 'index')
            ylabel(app.STC_Axes_ev, 'eigen value')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stc_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end